classdef lab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        titleLabel                     matlab.ui.control.Label
        GraficarButton                 matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        ftTab                          matlab.ui.container.Tab
        TipodeSealDropDownLabel        matlab.ui.control.Label
        TipodeSealDropDown             matlab.ui.control.DropDown
        Panel_2                        matlab.ui.container.Panel
        ecuacionLabel_xt               matlab.ui.control.Label
        campo1_xtLabel                 matlab.ui.control.Label
        campo1_xt                      matlab.ui.control.NumericEditField
        campo2_xtLabel                 matlab.ui.control.Label
        campo2_xt                      matlab.ui.control.NumericEditField
        campo3_xtLabel                 matlab.ui.control.Label
        campo3_xt                      matlab.ui.control.NumericEditField
        campo4_xtLabel                 matlab.ui.control.Label
        campo4_xt                      matlab.ui.control.NumericEditField
        htTab                          matlab.ui.container.Tab
        Panel_3                        matlab.ui.container.Panel
        ecuacionLabel_ht               matlab.ui.control.Label
        campo4_ht                      matlab.ui.control.NumericEditField
        campo4_htLabel                 matlab.ui.control.Label
        campo3_ht                      matlab.ui.control.NumericEditField
        campo3_htLabel                 matlab.ui.control.Label
        campo2_ht                      matlab.ui.control.NumericEditField
        campo2_htLabel                 matlab.ui.control.Label
        campo1_ht                      matlab.ui.control.NumericEditField
        campo1_htLabel                 matlab.ui.control.Label
        TipodeSealDropDown_2Label      matlab.ui.control.Label
        TipodeSealDropDown_2           matlab.ui.control.DropDown
        ConvolucionarButton            matlab.ui.control.Button
        DominiodelTiempoDropDownLabel  matlab.ui.control.Label
        DominiodelTiempoDropDown       matlab.ui.control.DropDown
        axposicion                     matlab.ui.control.UIAxes
        axconvo                        matlab.ui.control.UIAxes
        Image                          matlab.ui.control.Image
        infoLabel                      matlab.ui.control.Label
        axeht                          matlab.ui.control.UIAxes
        axeft                          matlab.ui.control.UIAxes
        creditosLabel                  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function setf(~,xval,yval)
            global f
            f=[xval;yval];
        end
        
        function seth(~,xval,yval)
            global h 
            h=[xval;yval];
        end        
    end
    
    methods
        function showCamposXT1(app, bool1, bool2)
            app.campo1_xt.Visible = bool1;
            app.campo1_xtLabel.Visible = bool1;
            app.campo2_xt.Visible = bool2; 
            app.campo2_xtLabel.Visible = bool2;         
        end
        function showCamposXT2(app, bool1, bool2)
            app.campo3_xt.Visible = bool1;
            app.campo3_xtLabel.Visible = bool1;
            app.campo4_xt.Visible = bool2;   
            app.campo4_xtLabel.Visible = bool2;       
        end
        function showCamposHT1(app, bool1, bool2)
            app.campo1_ht.Visible = bool1;
            app.campo1_htLabel.Visible = bool1;
            app.campo2_ht.Visible = bool2;   
            app.campo2_htLabel.Visible = bool2;        
        end
        function showCamposHT2(app, bool1, bool2)
            app.campo3_ht.Visible = bool1;
            app.campo3_htLabel.Visible = bool1;
            app.campo4_ht.Visible = bool2;     
            app.campo4_htLabel.Visible = bool2;        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Panel_2, TipodeSealDropDown
        function DropDownValueChanged(app, event)
            tipo_funcion = app.TipodeSealDropDown.Value;
            
            switch tipo_funcion
                case " "
                    app.Panel_2.Visible = false;                    
                case "Seno"
                    app.Panel_2.Visible = true;
                    app.campo1_xtLabel.Text = "Amplitud: ";
                    app.campo2_xtLabel.Text = "Período: ";
                    app.campo3_xtLabel.Text = "";
                    app.campo4_xtLabel.Text = "";
                    if app.DominiodelTiempoDropDown == "Discreta"
                        app.ecuacionLabel_xt.interpreter
                        app.ecuacionLabel_xt.Text = "y[n]=A\sin{2\pi n/N_{o}";
                    else
                        app.ecuacionLabel_xt.Text = "y(t)=A\sin{2\pi t/T}";
                    end
                    app.ecuacionLabel_xt.Visible = true;
                    showCamposXT1(app, true, true);
                    showCamposXT2(app, false, false);
                    
                case "Cuadrada"
                    app.Panel_2.Visible = true;
                    app.campo1_xtLabel.Text = "Amplitud: ";
                    app.campo2_xtLabel.Text = "Período: ";
                    app.campo3_xtLabel.Text = "";
                    app.campo4_xtLabel.Text = "";
                    if app.DominiodelTiempoDropDown == "Discreta"
                        app.ecuacionLabel_xt.interpreter
                        app.ecuacionLabel_xt.Text = "Señal Cuadrada";
                    else
                        app.ecuacionLabel_xt.Text = "Señal Cuadrada";
                    end
                    app.ecuacionLabel_xt.Visible = true;
                    showCamposXT1(app, true, true);
                    showCamposXT2(app, false, false);
                    
                case "Triangular"
                    app.Panel_2.Visible = true;
                    app.campo1_xtLabel.Text = "Amplitud: ";
                    app.campo2_xtLabel.Text = "Período: ";
                    app.campo3_xtLabel.Text = "";
                    app.campo4_xtLabel.Text = "";
                    if app.DominiodelTiempoDropDown == "Discreta"
                        app.ecuacionLabel_xt.interpreter
                        app.ecuacionLabel_xt.Text = "Señal Triangular";
                    else
                        app.ecuacionLabel_xt.Text = "Señal Triangular";
                    end
                    app.ecuacionLabel_xt.Visible = true;
                    showCamposXT1(app, true, true);
                    showCamposXT2(app, false, false);
                    
                case "Exponencial"
                    app.Panel_2.Visible = true;
                    app.campo1_xtLabel.Text = "Amplitud: ";
                    app.campo2_xtLabel.Text = "Exponente (b): ";
                    app.campo3_xtLabel.Text = "Lím. Inferior: ";
                    app.campo4_xtLabel.Text = "Lím. Superior: ";
                    if app.DominiodelTiempoDropDown == "Discreta"
                        app.ecuacionLabel_xt.interpreter
                        app.ecuacionLabel_xt.Text = "y[n]=A*exp(-b*n)";
                    else
                        app.ecuacionLabel_xt.Text = "y(t)=A*exp(-b*t)";
                    end
                    app.ecuacionLabel_xt.Visible = true;
                    showCamposXT1(app, true, true);
                    showCamposXT2(app, true, true); 
                    
                case "Rampa 1"
                    app.Panel_2.Visible = true;
                    app.campo1_xtLabel.Text = "Lím. Inferior: ";
                    app.campo2_xtLabel.Text = "Lím. Superior: ";
                    app.campo3_xtLabel.Text = "";
                    app.campo4_xtLabel.Text = "";
                    if app.DominiodelTiempoDropDown == "Discreta"
                        app.ecuacionLabel_xt.interpreter
                        app.ecuacionLabel_xt.Text = "Señal Rampa 1";
                    else
                        app.ecuacionLabel_xt.Text = "Señal Rampa 1";
                    end
                    app.ecuacionLabel_xt.Visible = true;
                    showCamposXT1(app, true, true);
                    showCamposXT2(app, false, false); 
                    
                case "Rampa 2"
                    app.Panel_2.Visible = true;
                    app.campo1_xtLabel.Text = "Lím. Inferior: ";
                    app.campo2_xtLabel.Text = "Lím. Superior: ";
                    app.campo3_xtLabel.Text = "";
                    app.campo4_xtLabel.Text = "";
                    if app.DominiodelTiempoDropDown == "Discreta"
                        app.ecuacionLabel_xt.interpreter
                        app.ecuacionLabel_xt.Text = "Señal Rampa 2";
                    else
                        app.ecuacionLabel_xt.Text = "Señal Rampa 2";
                    end
                    app.ecuacionLabel_xt.Visible = true;
                    showCamposXT1(app, true, true);
                    showCamposXT2(app, false, false);   
                    
                case "Rampa 3"
                    app.Panel_2.Visible = true;
                    app.campo1_xtLabel.Text = "Lím. Inferior: ";
                    app.campo2_xtLabel.Text = "Lím. Superior: ";
                    app.campo3_xtLabel.Text = "";
                    app.campo4_xtLabel.Text = "";
                    if app.DominiodelTiempoDropDown == "Discreta"
                        app.ecuacionLabel_xt.interpreter
                        app.ecuacionLabel_xt.Text = "Señal Rampa 3";
                    else
                        app.ecuacionLabel_xt.Text = "Señal Rampa 3";
                    end
                    app.ecuacionLabel_xt.Visible = true;
                    showCamposXT1(app, true, true);
                    showCamposXT2(app, false, false);  
            end
            
            %   Señal impulso h(h)
            tipo_funcionh = app.TipodeSealDropDown_2.Value;
            
            switch tipo_funcionh 
                case " "
                    app.Panel_3.Visible = false;                    
                case "Seno"
                    app.Panel_3.Visible = true;
                    app.campo1_htLabel.Text = "Amplitud: ";
                    app.campo2_htLabel.Text = "Lím. Inferior: ";
                    app.campo3_htLabel.Text = "Lím. Superior: ";
                    app.campo4_htLabel.Text = "";
                    if app.DominiodelTiempoDropDown == "Discreta"
                        app.ecuacionLabel_ht.Text = "y[n]=A\sin{2\pi n/N_{o}";
                    else
                        app.ecuacionLabel_ht.Text = "y(t)=A\sin{2\pi t/T}";
                    end
                    app.ecuacionLabel_xt.Visible = true;
                    showCamposHT1(app, true, true);
                    showCamposHT2(app, true, false);
                    
                case "Cuadrada"
                    app.Panel_3.Visible = true;
                    app.campo1_htLabel.Text = "Amplitud: ";
                    app.campo2_htLabel.Text = "Lím. Inferior: ";
                    app.campo3_htLabel.Text = "Lím. Superior: ";
                    app.campo4_htLabel.Text = "";
                    if app.DominiodelTiempoDropDown == "Discreta"
                        app.ecuacionLabel_ht.Text = "Señal Cuadrada";
                    else
                        app.ecuacionLabel_ht.Text = "Señal Cuadrada";
                    end
                    app.ecuacionLabel_xt.Visible = true;
                    showCamposHT1(app, true, true);
                    showCamposHT2(app, true, false);
                    
                case "Triangular"
                    app.Panel_3.Visible = true;
                    app.campo1_htLabel.Text = "Amplitud: ";
                    app.campo2_htLabel.Text = "Lím. Inferior: ";
                    app.campo3_htLabel.Text = "Lím. Superior: ";
                    app.campo4_htLabel.Text = "";
                    if app.DominiodelTiempoDropDown == "Discreta"
                        app.ecuacionLabel_ht.Text = "Señal Triangular";
                    else
                        app.ecuacionLabel_ht.Text = "Señal Triangular";
                    end
                    app.ecuacionLabel_xt.Visible = true;
                    showCamposHT1(app, true, true);
                    showCamposHT2(app, true, false);
                    
                case "Exponencial"
                    app.Panel_3.Visible = true;
                    app.campo1_htLabel.Text = "Amplitud: ";
                    app.campo2_htLabel.Text = "Exponente (b): ";
                    app.campo3_htLabel.Text = "Lím. Inferior: ";
                    app.campo4_htLabel.Text = "Lím. Superior: ";
                    if app.DominiodelTiempoDropDown == "Discreta"
                        app.ecuacionLabel_ht.Text = "y[n]=A*exp(-b*n)";
                    else
                        app.ecuacionLabel_ht.Text = "y(t)=A*exp(-b*t)";
                    end
                    app.ecuacionLabel_xt.Visible = true;
                    showCamposHT1(app, true, true);
                    showCamposHT2(app, true, true); 
                    
                case "Rampa 1"
                    app.Panel_3.Visible = true;
                    app.campo1_htLabel.Text = "Lím. Inferior: ";
                    app.campo2_htLabel.Text = "Lím. Superior: ";
                    app.campo3_htLabel.Text = "";
                    app.campo4_htLabel.Text = "";
                    if app.DominiodelTiempoDropDown == "Discreta"
                        app.ecuacionLabel_ht.Text = "Señal Rampa 1";
                    else
                        app.ecuacionLabel_ht.Text = "Señal Rampa 1";
                    end
                    app.ecuacionLabel_xt.Visible = true;
                    showCamposHT1(app, true, true);
                    showCamposHT2(app, false, false); 
                    
                case "Rampa 2"
                    app.Panel_3.Visible = true;
                    app.campo1_htLabel.Text = "Lím. Inferior: ";
                    app.campo2_htLabel.Text = "Lím. Superior: ";
                    app.campo3_htLabel.Text = "";
                    app.campo4_htLabel.Text = "";
                    if app.DominiodelTiempoDropDown == "Discreta"
                        app.ecuacionLabel_ht.Text = "Señal Rampa 2";
                    else
                        app.ecuacionLabel_ht.Text = "Señal Rampa 2";
                    end
                    app.ecuacionLabel_xt.Visible = true;
                    showCamposHT1(app, true, true);
                    showCamposHT2(app, false, false);   
                    
                case "Rampa 3"
                    app.Panel_3.Visible = true;
                    app.campo1_htLabel.Text = "Lím. Inferior: ";
                    app.campo2_htLabel.Text = "Lím. Superior: ";
                    app.campo3_htLabel.Text = "";
                    app.campo4_htLabel.Text = "";
                    if app.DominiodelTiempoDropDown == "Discreta"
                        app.ecuacionLabel_ht.Text = "Señal Rampa 3";
                    else
                        app.ecuacionLabel_ht.Text = "Señal Rampa 3";
                    end
                    app.ecuacionLabel_xt.Visible = true;
                    showCamposHT1(app, true, true);
                    showCamposHT2(app, false, false);   
            end
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            
            if (app.TipodeSealDropDown.Value~=" " && app.TipodeSealDropDown_2~=" ")
            
                
            else
                
                
            end
        end

        % Value changed function: DominiodelTiempoDropDown
        function DominiodelTiempoDropDownValueChanged(app, event)
            value = app.DominiodelTiempoDropDown.Value;
            
            switch value
                case " "
                    app.TipodeSealDropDown.Enable = false;
                    app.TipodeSealDropDown_2.Enable = false;
                    app.ConvolucionarButton.Visible=false;
                    app.GraficarButton.Visible=false;
                    showCamposHT1(app, false, false);
                    showCamposHT2(app, false, false);
                    showCamposXT1(app, false, false);
                    showCamposXT2(app, false, false);
                    
                case "Discreta"
                    app.TipodeSealDropDown.Enable = true;
                    app.TipodeSealDropDown_2.Enable = true;
                    app.ftTab.Title="f[n]";
                    app.htTab.Title="h[n]";
                    app.axeft.Title.String="f[n]";
                    app.axeht.Title.String="h[n]";
                    app.axeft.XLabel.String="n";
                    app.axeht.XLabel.String="n";
                    app.axconvo.XLabel.String="n";
                    app.axposicion.XLabel.String="n";
                    app.axeft.YLabel.String="f[n]";
                    app.axeht.YLabel.String="h[n]";
                    app.axconvo.YLabel.String="f[n]*h[n]";
                    app.axposicion.YLabel.String="f[n]*h[n]";
                    app.axconvo.Visible=true;
                    app.axeft.Visible=true;
                    app.axeht.Visible=true;
                    app.axposicion.Visible=true;
                    
                    app.TabGroup.Visible=true;
                    app.ConvolucionarButton.Visible=true;
                    app.GraficarButton.Visible=true;
                    switch app.TipodeSealDropDown.Value
                        case "Seno"
                            app.ecuacionLabel_xt.Text = "y[n]=A*Sen(2*π*[n]/No)";
                        case "Exponencial"
                            app.ecuacionLabel_xt.Text = "y[n]=A*exp(-b*n)";                            
                    end
                    switch app.TipodeSealDropDown_2.Value
                        case "Seno"
                            app.ecuacionLabel_ht.Text = "y[n]=A*Sen(2*π*[n]/No)";
                        case "Exponencial"
                            app.ecuacionLabel_ht.Text = "y[n]=A*exp(-b*n)";                    
                    end
                        
                case "Continua"
                    app.TipodeSealDropDown.Enable = true;
                    app.TipodeSealDropDown_2.Enable = true;
                    app.ftTab.Title="f(t)";
                    app.htTab.Title="h(t)";
                    app.axeft.Title.String="f(t)";
                    app.axeht.Title.String="h(t)";
                    app.axeft.XLabel.String="t";
                    app.axeht.XLabel.String="t";
                    app.axposicion.XLabel.String="t";
                    app.axconvo.XLabel.String="t";
                    app.axeft.YLabel.String="f(t)";
                    app.axeht.YLabel.String="h(t)";
                    app.axposicion.YLabel.String="f(t)*h(t)";
                    app.axconvo.YLabel.String="f(t)*h(t)";
                    app.axconvo.Visible=true;
                    app.axeft.Visible=true;
                    app.axeht.Visible=true;
                    app.axposicion.Visible=true;
                    app.TabGroup.Visible=true;
                    
                    app.ConvolucionarButton.Visible=true;
                    app.GraficarButton.Visible=true;
                    switch app.TipodeSealDropDown.Value
                        case "Seno"
                            app.ecuacionLabel_xt.Text = "y[n]=A*Sen(2*π*[n]/No)";
                        case "Exponencial"
                            app.ecuacionLabel_xt.Text = "y[n]=A*exp(-b*n)";                            
                    end
                    switch app.TipodeSealDropDown_2.Value
                        case "Seno"
                            app.ecuacionLabel_ht.Text = "y[n]=A*Sen(2*π*[n]/No)";
                        case "Exponencial"
                            app.ecuacionLabel_ht.Text = "y[n]=A*exp(-b*n)";                    
                    end                   
                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.titleLabel = uilabel(app.UIFigure);
            app.titleLabel.Text = 'Convolución de señales';
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ftTab = uitab(app.TabGroup);
            app.ftTab.Title = 'f(t)';
            app.TipodeSealDropDownLabel = uilabel(app.ftTab);
            app.TipodeSealDropDownLabel.Text = 'Tipo de Señal';
            app.TipodeSealDropDown = uidropdown(app.ftTab);
            app.TipodeSealDropDown.Items = {' ', 'Seno', 'Cuadrada', 'Triangular', 'Exponencial', 'Rampa 1', 'Rampa 2', 'Rampa 3'};
            app.TipodeSealDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.TipodeSealDropDown.Enable = 'off';
            app.TipodeSealDropDown.Value = ' ';
            app.Panel_2 = uipanel(app.ftTab);
            app.Panel_2.Visible = 'off';
            app.Panel_2.SizeChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.ecuacionLabel_xt = uilabel(app.Panel_2);
            app.ecuacionLabel_xt.Text = 'a';
            app.campo1_xtLabel = uilabel(app.Panel_2);
            app.campo1_xtLabel.Text = 'campo1_xt';
            app.campo1_xt = uieditfield(app.Panel_2, 'numeric');
            app.campo2_xtLabel = uilabel(app.Panel_2);
            app.campo2_xtLabel.Text = 'campo2_xt';
            app.campo2_xt = uieditfield(app.Panel_2, 'numeric');
            app.campo3_xtLabel = uilabel(app.Panel_2);
            app.campo3_xtLabel.Text = 'campo3_xt';
            app.campo3_xt = uieditfield(app.Panel_2, 'numeric');
            app.campo4_xtLabel = uilabel(app.Panel_2);
            app.campo4_xtLabel.Text = 'campo4_xt';
            app.campo4_xt = uieditfield(app.Panel_2, 'numeric');
            app.htTab = uitab(app.TabGroup);
            app.htTab.Title = 'h(t)';
            app.Panel_3 = uipanel(app.htTab);
            app.ecuacionLabel_ht = uilabel(app.Panel_3);
            app.ecuacionLabel_ht.Text = 'a';
            app.campo4_ht = uieditfield(app.Panel_3, 'numeric');
            app.campo4_htLabel = uilabel(app.Panel_3);
            app.campo4_htLabel.Text = 'campo4_ht';
            app.campo3_ht = uieditfield(app.Panel_3, 'numeric');
            app.campo3_htLabel = uilabel(app.Panel_3);
            app.campo3_htLabel.Text = 'campo3_ht';
            app.campo2_ht = uieditfield(app.Panel_3, 'numeric');
            app.campo2_htLabel = uilabel(app.Panel_3);
            app.campo2_htLabel.Text = 'campo2_ht';
            app.campo1_ht = uieditfield(app.Panel_3, 'numeric');
            app.campo1_htLabel = uilabel(app.Panel_3);
            app.campo1_htLabel.Text = 'campo1_ht';
            app.TipodeSealDropDown_2Label = uilabel(app.htTab);
            app.TipodeSealDropDown_2Label.Text = 'Tipo de Señal';
            app.TipodeSealDropDown_2 = uidropdown(app.htTab);
            app.TipodeSealDropDown_2.Items = {' ', 'Seno', 'Exponencial', 'Cuadrada', 'Triangular', 'Rampa 1', 'Rampa 2', 'Rampa 3'};
            app.TipodeSealDropDown_2.Enable = 'off';
            app.TipodeSealDropDown_2.Value = ' ';
            app.ConvolucionarButton = uibutton(app.UIFigure, 'push');
            app.ConvolucionarButton.Text = 'Convolucionar';
            app.DominiodelTiempoDropDownLabel = uilabel(app.UIFigure);
            app.DominiodelTiempoDropDownLabel.Text = 'Dominio del Tiempo';
            app.DominiodelTiempoDropDown = uidropdown(app.UIFigure);
            app.DominiodelTiempoDropDown.Items = {' ', 'Continua', 'Discreta'};
            app.DominiodelTiempoDropDown.ValueChangedFcn = createCallbackFcn(app, @DominiodelTiempoDropDownValueChanged, true);
            app.DominiodelTiempoDropDown.Value = ' ';
            app.axposicion = uiaxes(app.UIFigure);
            title(app.axposicion, 'Posición')
            xlabel(app.axposicion, 't')
            ylabel(app.axposicion, 'y(t)')
            app.axconvo = uiaxes(app.UIFigure);
            title(app.axconvo, 'Convolución')
            xlabel(app.axconvo, 't')
            ylabel(app.axconvo, 'y(t)')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'un_logo.png';
            app.infoLabel = uilabel(app.UIFigure);
            app.infoLabel.Text = {'Seleccione el dominio y las '; 'funciones a graficar:'};
            app.axeht = uiaxes(app.UIFigure);
            title(app.axeht, '')
            xlabel(app.axeht, 't')
            ylabel(app.axeht, 'y(t)')
            app.axeft = uiaxes(app.UIFigure);
            title(app.axeft, '')
            xlabel(app.axeft, 't')
            ylabel(app.axeft, 'y(t)')
            app.creditosLabel = uilabel(app.UIFigure);
            app.creditosLabel.Text = 'Realizado por David Beltrán y Enrique Niebles.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end